classdef main_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ResultsTextArea              matlab.ui.control.TextArea
        ResultsLabel                 matlab.ui.control.Label
        SelectFeature3DropDown       matlab.ui.control.DropDown
        SelectFeature3DropDownLabel  matlab.ui.control.Label
        SelectFeature2DropDown       matlab.ui.control.DropDown
        SelectFeature2DropDownLabel  matlab.ui.control.Label
        RunAnalysisButton            matlab.ui.control.Button
        SelectFeature1DropDown       matlab.ui.control.DropDown
        SelectFeature1DropDownLabel  matlab.ui.control.Label
        UploadImageButton            matlab.ui.control.Button
        ImageAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         ImagePath string = ""; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg', 'Image Files (*.png, *.jpg, *.jpeg)'});
            if isequal(file,0)
                return;
            end
        
            app.ImagePath = fullfile(path, file);
        
            % Display the image in UIAxes instead of uiimage
            img = imread(app.ImagePath);
            imshow(img, 'Parent', app.ImageAxes); % ✅ Correct way to show image
        end

        % Value changed function: SelectFeature1DropDown
        function SelectFeature1DropDownValueChanged2(app, event)
            selectedFeature = app.SelectFeature1DropDown.Value; % Get selected feature
            disp(['Feature Selected: ', selectedFeature]); % Debugging output
        end

        % Callback function
        function SelectFeatureDropDownValueChanged(app, event)
            selectedStyle = app.SelectStyleDropDown.Value;

            featureOptions = {
                'Baseline Detection', 'Slant Detection', 'Letter Spacing', 'Stroke Continuity', 
                'Line Quality', 'Pressure Pattern', 'Letter Formation', 'Stroke Thickness',
                'Stroke Curvature', 'Stroke Smoothness', 'Stroke Angle Variation'
            };

            % Update Dropdowns
            app.SelectFeature1DropDown.Items = featureOptions;
            app.SelectFeature2DropDown.Items = featureOptions;
            app.SelectFeature3DropDown.Items = featureOptions;
        end

        % Callback function
        function UIAxesButtonDown(app, event)
                    
        end

        % Callback function
        function WritingStyleDropDown(app, event)
            value = app.SelectStyleDropDown.Value;
            
        end

        % Value changed function: SelectFeature3DropDown
        function SelectFeature3DropDownValueChanged(app, event)
            value = app.SelectFeature3DropDown.Value;
            
        end

        % Value changed function: SelectFeature2DropDown
        function SelectFeature2DropDownValueChanged(app, event)
            value = app.SelectFeature2DropDown.Value;
            
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
           try
                % Ensure the results text area is cleared before running
                app.ResultsTextArea.Value = {'Running analysis...'};
                
                % Get Selected Features
                selectedScripts = {app.SelectFeature1DropDown.Value, app.SelectFeature2DropDown.Value, app.SelectFeature3DropDown.Value};
                
                % Remove empty selections (if the third dropdown is not used)
                selectedScripts = selectedScripts(~cellfun('isempty', selectedScripts));
        
                % Map Dropdown Selection to Function Names
                scriptMap = containers.Map( ...
                    {'Baseline Detection', 'Slant Detection', 'Letter Spacing', 'Stroke Continuity', ...
                     'Line Quality', 'Pressure Pattern', 'Letter Formation', ...
                     'Stroke Thickness', 'Stroke Curvature', 'Stroke Smoothness', 'Stroke Angle Variation', ...
                     'Aspect_Ratio','Contour_Complexity','Loop_Density','Pen_Pressure_Gradient','Stroke_Fragmentation','Vertical_Stroke_Density'}, ...
                    {@baseline_detection, @slant_detection, @space_detection, @stroke_detection, ...
                     @line_quality, @pressure_pattern, @letter_formation, ...
                     @stroke_thickness, @stroke_curvature, @stroke_smoothness, @stroke_angle_variation, ...
                     @Aspect_Ratio, @Contour_Complexity,@Loop_Density, @Pen_Pressure_Gradient, @Stroke_Fragmentation, @Vertical_Stroke_Density} ...
                );

                % Log File Path
                projectRoot = fileparts(mfilename('fullpath')); % Get the root folder of the running script
                logFilePath = fullfile(projectRoot, '..', 'log.txt'); % Go up one level and save in main folder
        
                % Open Log File for Writing (Overwrite)
                logFile = fopen(logFilePath, 'w');
                fprintf(logFile, "=== Handwriting Analysis Log ===\n");
                fprintf(logFile, "Image Processed: %s\n\n", app.ImagePath);
                fclose(logFile); % Close to prevent locking the file
        
                % Loop through selected scripts and run them one by one
                for i = 1:length(selectedScripts)
                    selectedFeature = selectedScripts{i};
        
                    if scriptMap.isKey(selectedFeature)
                        featureFunction = scriptMap(selectedFeature);
                        
                        % Run the script and get results
                        result = featureFunction(app.ImagePath);
                        
                        % Append result to log
                        logFile = fopen(logFilePath, 'a'); % Append mode
                        fprintf(logFile, "Running: %s\n", selectedFeature);
                        
                        % Display Results in Text Area (Formatted for Readability)
                        outputText = {['--- ', selectedFeature, ' ---']}; % Section header
                        
                        if isfield(result, 'AvgComponentSize')
                            outputText = [outputText; ...
                                sprintf('Avg Component Size: %.4f', result.AvgComponentSize); ...
                                ['Type: ', result.Type]; ...
                                ' ']; % Blank line for spacing
                        elseif isfield(result, 'MeanSpacing')
                            outputText = [outputText; ...
                                sprintf('Mean Spacing: %.4f', result.MeanSpacing); ...
                                sprintf('CV: %.4f', result.CVSpacing); ...
                                ['Spacing Type: ', result.Type]; ...
                                ' '];  
                        elseif isfield(result, 'Angle')
                            outputText = [outputText; ...
                                sprintf('Slant Angle: %.4f°', result.Angle); ...
                                ['Slant Type: ', result.Type]; ...
                                ' '];  
                        elseif isfield(result, 'Result')
                            outputText = [outputText; ...
                                sprintf('Result Score: %.4f°', result.Result); ...
                                ['Type: ', result.Type]; ...
                                ' '];
                        elseif isfield(result, 'Score')
                            outputText = [outputText; ...
                                sprintf('Baseline Score: %.4f', result.Score); ...
                                ['Type: ', result.Type]; ...
                                ' '];  
                        else
                            outputText = [outputText; {'No valid result found.'}; ' '];
                        end
        
                        % Update GUI (Append Results Instead of Overwriting)
                        app.ResultsTextArea.Value = [app.ResultsTextArea.Value; outputText(:)];
        
                        % Write outputText to Log File
                        for j = 1:length(outputText)
                            fprintf(logFile, "%s\n", outputText{j});
                        end
                        fprintf(logFile, "\n");
        
                        fclose(logFile); % Close log file
                    else
                        error('Invalid feature selection: %s', selectedFeature);
                    end
                end
        
                % Display success message
                app.ResultsTextArea.Value = [app.ResultsTextArea.Value; {'Analysis Complete.'}];
        
            catch ME
                % Log the error
                logFile = fopen(logFilePath, 'a');
                fprintf(logFile, "ERROR: %s\n", ME.message);
                fclose(logFile);
        
                % Show error in the text area
                app.ResultsTextArea.Value = {'Error occurred! Check log file.'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Title')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            zlabel(app.ImageAxes, 'Z')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.SelectFeature1DropDownLabel = uilabel(app.UIFigure);
            app.SelectFeature1DropDownLabel.Text = 'Select Feature 1';
            app.SelectFeature1DropDown = uidropdown(app.UIFigure);
            app.SelectFeature1DropDown.Items = {'Baseline Detection', 'Slant Detection', 'Letter Spacing', 'Stroke Continuity', 'Line Quality', 'Pressure Pattern', 'Letter Formation', 'Stroke Thickness', 'Stroke Curvature', 'Stroke Smoothness', 'Stroke Angle Variation', 'Aspect_Ratio', 'Contour_Complexity', 'Loop_Density', 'Pen_Pressure_Gradient', 'Stroke_Fragmentation', 'Vertical_Stroke_Density'};
            app.SelectFeature1DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFeature1DropDownValueChanged2, true);
            app.SelectFeature1DropDown.Placeholder = 'Select Script 1';
            app.SelectFeature1DropDown.Value = 'Baseline Detection';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.SelectFeature2DropDownLabel = uilabel(app.UIFigure);
            app.SelectFeature2DropDownLabel.Text = 'Select Feature 2';
            app.SelectFeature2DropDown = uidropdown(app.UIFigure);
            app.SelectFeature2DropDown.Items = {'Baseline Detection', 'Slant Detection', 'Letter Spacing', 'Stroke Continuity', 'Line Quality', 'Pressure Pattern', 'Letter Formation', 'Stroke Thickness', 'Stroke Curvature', 'Stroke Smoothness', 'Stroke Angle Variation', 'Aspect_Ratio', 'Contour_Complexity', 'Loop_Density', 'Pen_Pressure_Gradient', 'Stroke_Fragmentation', 'Vertical_Stroke_Density'};
            app.SelectFeature2DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFeature2DropDownValueChanged, true);
            app.SelectFeature2DropDown.Placeholder = 'Select Script 2';
            app.SelectFeature2DropDown.Value = 'Baseline Detection';
            app.SelectFeature3DropDownLabel = uilabel(app.UIFigure);
            app.SelectFeature3DropDownLabel.Text = 'Select Feature 3';
            app.SelectFeature3DropDown = uidropdown(app.UIFigure);
            app.SelectFeature3DropDown.Items = {'Baseline Detection', 'Slant Detection', 'Letter Spacing', 'Stroke Continuity', 'Line Quality', 'Pressure Pattern', 'Letter Formation', 'Stroke Thickness', 'Stroke Curvature', 'Stroke Smoothness', 'Stroke Angle Variation', 'Aspect_Ratio', 'Contour_Complexity', 'Loop_Density', 'Pen_Pressure_Gradient', 'Stroke_Fragmentation', 'Vertical_Stroke_Density'};
            app.SelectFeature3DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFeature3DropDownValueChanged, true);
            app.SelectFeature3DropDown.Placeholder = 'Select Script 3';
            app.SelectFeature3DropDown.Value = 'Baseline Detection';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.ResultsTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end